classdef WaveformCutter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        WaveformCutterAppLabel          matlab.ui.control.Label
        CutspikesfromwaveformButton     matlab.ui.control.Button
        TimepointEditFieldLabel         matlab.ui.control.Label
        TimepointEditField              matlab.ui.control.NumericEditField
        LowerthresholdEditFieldLabel    matlab.ui.control.Label
        LowerthresholdEditField         matlab.ui.control.NumericEditField
        Selectclu1fileButton            matlab.ui.control.Button
        UpperthresholdEditFieldLabel    matlab.ui.control.Label
        UpperthresholdEditField         matlab.ui.control.NumericEditField
        DiscardedspikeswillbeplacedinclusternumberLabel  matlab.ui.control.Label
        BatchsizeEditFieldLabel         matlab.ui.control.Label
        BatchsizeEditField              matlab.ui.control.NumericEditField
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SavechangesButton               matlab.ui.control.Button
        SelectaclusterLabel             matlab.ui.control.Label
        SelectaclusterDropDown          matlab.ui.control.DropDown
        SelectachannelDropDownLabel     matlab.ui.control.Label
        SelectachannelDropDown          matlab.ui.control.DropDown
        GlobalHistogram                 matlab.ui.control.UIAxes
        HistogramdurationmsEditFieldLabel  matlab.ui.control.Label
        HistogramdurationmsEditField    matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        PlotbinsinradiusEditFieldLabel  matlab.ui.control.Label
        PlotbinsinradiusEditField       matlab.ui.control.NumericEditField
        CutspikesfrombinButton          matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        PreviousButton_2                matlab.ui.control.Button
        BatchsizeEditField_2Label       matlab.ui.control.Label
        BatchsizeEditField_2            matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        GlobalPath %Path to the clu file
        GlobalBasename %Basename of the clu file
        GlobalSamplingFrequency = 24414.16;
        GlobalClusterNumber = 0; %Cluster to show
        GlobalChannelNumber = 1; %Channel to show
        GlobalTimepointNumber = 0; %Used to select spikes to delete 
        GlobalUpperThreshold = 0; %Used to select spikes to delete
        GlobalLowerThreshold = 0; %Used to select spikes to delete
        GlobalNewClusterNumber = 0; %Cluster to which removed spikes will be sent
        GlobalBatchSize = 100; %Number of spikes displayed for each batch
        GlobalBatchIndex = 1; %Current batch number being displayed (starting at 1)
        GlobalMaxBatchIndex = 1; %Number of batches = number of spikes / batch size
        
        GlobalAmplitude = 400;
        
        GlobalBatchSize_2 = 100;
        GlobalBatchIndex_2 = 1;
        GlobalMaxBatchIndex_2 = 1;
        
        GlobalHistogramDuration = 20; %Max time distance between spikes to observe
        GlobalNumberOfChannels = 1;
        
        %These are global references to the data
        GlobalCluArray %This array holds all clusters?
        GlobalTimepointsArray
        GlobalSpikeWaveforms
        
        %These are global references to xline and yline
        GlobalX1Line; %Used to select spikes to delete
        GlobalX2Line; %Used to select spikes to delete
        GlobalYLine;  %Used to select spikes to delete
        
        %These variables are used to plot waveforms based on the histogram
        HistogramPlotRadius = 1; %Plot spikes with a separation of these many nanoseconds
        HistogramChannelNumber = 1; %Channel to be displayed
        HistogramTimepointNumber = 0; %Used to select spikes to delete
        HistogramUpperThreshold = 0; %Used to select spikes to delete
        HistogramLowerThreshold = 0; %Used to select spikes to delete
        HistogramX1Line; %Used to select spikes to delete
        HistogramX2Line; %Used to select spikes to delete
        HistogramYLine; %Used to select spikes to delete
        
        
    end
    
    methods (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [spike_waveforms, spike_timepoint, spike_clusters] = load_klusters_output(app, path, basename)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Loading the data.','Cancelable','on');
            %path is the directory where you have the clu, spk, dat, fet and res files
            %that you want to open. Make sure they are ALL there or you will get an
            %error. All of your files should have the same basename.
            
            %clu is an array with a single column and the data type is double. The
            %number of rows is number of spikes.
            spike_clusters = load(char(strcat(path, basename, '.clu.1')));
            spike_clusters = spike_clusters(2:end);
            spike_timepoint = load(char(strcat(path, basename, '.res.1')));
            xml_file = xml2struct(char(strcat(path, basename, '.xml')));
            app.GlobalSamplingFrequency = str2double(xml_file.parameters.acquisitionSystem.samplingRate.Text);
         
            timepoints_per_spike = 32;
            number_of_spikes = length(spike_timepoint);
            fid_spk=fopen(char(strcat(path, basename, '.spk.1')),'r');
            spk_temp = fread(fid_spk,'int16');
            number_of_channels = length(spk_temp)/(number_of_spikes*timepoints_per_spike);
            
            app.GlobalNumberOfChannels = number_of_channels;
            
            if (mod(number_of_channels,1) ~= 0)
                error('ATTENTION: CORRUPTED READING'); %This is a safety precaution
            end
            fclose(fid_spk);
            spike_waveforms = zeros(number_of_channels, timepoints_per_spike, number_of_spikes);
            k = 1;
            msg = [];
            for spikeIdx = 1:number_of_spikes
                d.Value = spikeIdx/number_of_spikes;
                if d.CancelRequested
                    break
                end
                for tIdx = 1:timepoints_per_spike
                    idxStart = ((spikeIdx - 1) * (timepoints_per_spike * number_of_channels)) + ((tIdx - 1) * number_of_channels) + 1;
                    idxVec = idxStart:(idxStart + number_of_channels - 1);
                    spike_waveforms(:, tIdx, spikeIdx) = spk_temp(idxVec);
                end
            end
            
            close(d);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function plotHistogram(app)
            hold(app.GlobalHistogram, 'off');
            sampling_frequency = app.GlobalSamplingFrequency; %need to atomate this in the future
            time_length = app.GlobalHistogramDuration; %ms
            time_length_in_timepoints = sampling_frequency * time_length * 10^-3;
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Plotting histogram.');
            
            %Figure out what spikes belong to our cluster
            SpikeToUse =[];
            TimesToUse =[];
            j = 1;
            for i=1:length(app.GlobalCluArray)
                if app.GlobalCluArray(i) == app.GlobalClusterNumber
                    SpikeToUse(j) = i;
                    TimesToUse(j) = app.GlobalTimepointsArray(i);
                    j = j +1;
                end
            end
            
            firing_rate = length(TimesToUse)/((TimesToUse(length(TimesToUse))-TimesToUse(1))/sampling_frequency);
            
            data = [];
            data_index = 1;
            
            for i=1:length(SpikeToUse)%For every spike in the cluster
                d.Value = i/length(SpikeToUse);
                before = 1;
                %Check spikes before this one
                while true
                    %If we run out of spikes, end the loop
                    if i-before < 1
                        break
                    end
                    %If spike is outside our time length, end the loop
                    if (TimesToUse(i) - TimesToUse(i-before) > time_length_in_timepoints)
                        break
                    end
                    temp = (10^3*(TimesToUse(i-before) - TimesToUse(i))/sampling_frequency)-0.5;
                    if (temp>-0.5)
                        temp = -1;
                    end
                    data(data_index) = int32(temp);
                    data_index = data_index + 1;
                    before = before + 1;
                end
                
                %Check spikes after this one
                after = 1;
                while true
                    if i+after > length(SpikeToUse)
                        break
                    end
                    if (TimesToUse(i+after) - TimesToUse(i) > time_length_in_timepoints)
                        break
                    end
                    temp = (10^3*(TimesToUse(i+after) - TimesToUse(i))/sampling_frequency)+0.5;
                    if (temp<0.5)
                        temp = 1;
                    end
                    data(data_index) = int32(temp); %We are storing the time difference in ms between the two spikes in "data" as an integer
                    data_index = data_index + 1;
                    after = after + 1;
                end
            end
            categories = [-time_length:-1, 1:time_length];
            histogram(app.GlobalHistogram, categorical(data,categories,cellstr(num2str(categories'))), 'FaceColor', 'b');
            hold(app.GlobalHistogram, 'on');
            x=get(app.GlobalHistogram,'xlim');
            y = ones(length(x))*firing_rate;
            line(app.GlobalHistogram, x, y);
            
            if isempty(SpikeToUse)
                app.Label_2.Text= 'This cluster is empty';
            end
            
            if ~isempty(SpikeToUse)
                app.Label_2.Text= ['Firing rate for the cluster: ' num2str(firing_rate)];
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function HistogramLoadWaveform(app, event) %THIS FUNCTION IS WRONG
            %first get an array with the spikes that belong to the cluster
            time_length_in_timepoints = app.GlobalSamplingFrequency * app.HistogramPlotRadius * 10^-3;
            
            d = uiprogressdlg(app.UIFigure,'Title','Plotting the data, please wait.',...
                'Indeterminate','on');
            
            %Figure out what spikes belong to our cluster
            SpikeToUse =[];
            TimesToUse =[];
            j = 1;
            for i=1:length(app.GlobalCluArray)
                if app.GlobalCluArray(i) == app.GlobalClusterNumber
                    SpikeToUse(j) = i; %This is storing the index of the spike that matches the cluster number
                    TimesToUse(j) = app.GlobalTimepointsArray(i); %This is storing the timepoint of the spike we just stored
                    j = j +1;
                end
            end
            
            %Figure out if the spikes that fired within the determined bin
            data = [];
            data_index = 1;
            
            for i=1:length(SpikeToUse)%For every spike in the cluster
                
                before = 1;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Check spikes before this one
                while true
                    %If we run out of spikes, end the loop
                    if i-before < 1
                        break
                    end
                    %If spike is outside our time length, end the loop
                    if (TimesToUse(i) - TimesToUse(i-before) > time_length_in_timepoints)
                        break
                    end
                    data(data_index) = SpikeToUse(i-before); %This spike meets the criteria
                    data_index = data_index + 1;
                    before = before + 1;
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Check spikes after this one
                after = 1;
                while true
                    if i+after > length(SpikeToUse)
                        break
                    end
                    if (TimesToUse(i+after) - TimesToUse(i) > time_length_in_timepoints)
                        break
                    end
                    data(data_index) = SpikeToUse(i+after);
                    data_index = data_index + 1;
                    after = after + 1;
                end
            end
            
            data = unique(data);
            %Now the varable data contains what we want
            app.GlobalMaxBatchIndex_2 = ceil(length(data)/app.GlobalBatchSize_2);
            if app.GlobalMaxBatchIndex_2 < 1
                app.GlobalMaxBatchIndex_2 = 1;
            end
            if app.GlobalBatchIndex_2>app.GlobalMaxBatchIndex_2
                app.GlobalBatchIndex_2 = app.GlobalMaxBatchIndex_2;
            end
            
            %shorten spikes to use
            NumberOfSpikes_2 = length(data);
            InitialIndex_2 = app.GlobalBatchSize_2*(app.GlobalBatchIndex_2-1)+1;
            FinalIndex_2 = InitialIndex_2 + app.GlobalBatchSize_2-1;
            if (FinalIndex_2 > NumberOfSpikes_2)
                FinalIndex_2 = NumberOfSpikes_2;
            end
            
            if isempty(data)
                app.Label_3.Text= 'This cluster is empty';
                
            end
            
            if ~isempty(data)
                app.Label_3.Text= ['Plotting spikes ' num2str(InitialIndex_2) ' to ' num2str(FinalIndex_2) ' out of ' num2str(NumberOfSpikes_2)];
            end


            %Plotting
            x = 1:32;
            hold(app.UIAxes_2, 'off');
            plot(app.UIAxes_2,0,0,'green');
            
            if ~isempty(data)
                data = data(InitialIndex_2:FinalIndex_2);
                
                hold(app.UIAxes_2, 'off');
                for i=1:app.GlobalNumberOfChannels %for every channel
                    color = 'red';
                    if i == app.HistogramChannelNumber
                        color = 'green';
                    end
                    for j=1:length(data) %for every spike
                        y = app.GlobalSpikeWaveforms(i, :, data(j)) + app.GlobalAmplitude*(app.HistogramChannelNumber-i);
                        plot(app.UIAxes_2,x,y,color);
                        hold(app.UIAxes_2, 'on');
                    end
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            LowerthresholdEditFieldValueChanged(app, event);
            UpperthresholdEditFieldValueChanged(app, event);
            TimepointEditFieldValueChanged(app, event)
            close(d);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Callback function
        function SelectaclusterEditFieldValueChanged(app, event)
            app.GlobalClusterNumber = app.SelectaclusterEditField.Value;
            
        end

        % Callback function
        function SelectachannelEditFieldValueChanged(app, event)
            app.GlobalChannelNumber = app.SelectachannelEditField.Value;
        end

        % Callback function
        function LoadwaveformsButtonPushed(app, event)
            %first get an array with the spikes that belong to the cluster
            d = uiprogressdlg(app.UIFigure,'Title','Plotting the data, please wait.',...
                'Indeterminate','on');
            SpikeToUse =[];
            j = 1;
            for i=1:length(app.GlobalCluArray)
                if app.GlobalCluArray(i) == app.GlobalClusterNumber
                    SpikeToUse(j) = i;
                    j = j +1;
                end
            end
            app.GlobalMaxBatchIndex = ceil(length(SpikeToUse)/app.GlobalBatchSize);
            if app.GlobalMaxBatchIndex < 1
                app.GlobalMaxBatchIndex = 1;
            end
            if app.GlobalBatchIndex>app.GlobalMaxBatchIndex
                app.GlobalBatchIndex = app.GlobalMaxBatchIndex;
            end
            
            %shorten spikes to use
            NumberOfSpikes = length(SpikeToUse);
            InitialIndex = app.GlobalBatchSize*(app.GlobalBatchIndex-1)+1;
            FinalIndex = InitialIndex + app.GlobalBatchSize-1;
            if (FinalIndex > NumberOfSpikes)
                FinalIndex = NumberOfSpikes;
            end
            
            if isempty(SpikeToUse)
                app.Label.Text= 'This cluster is empty';
                
            end
            
            if ~isempty(SpikeToUse)
                app.Label.Text= ['Plotting spikes ' num2str(InitialIndex) ' to ' num2str(FinalIndex) ' out of ' num2str(NumberOfSpikes)];
            end
            
            
            %Now the array SpikeToUse containes all the spikes we need
            %Time to plot it
            x = 1:32;
            
            hold(app.UIAxes, 'off');
            plot(app.UIAxes,0,0,'green');
            
            if ~isempty(SpikeToUse)
                SpikeToUse = SpikeToUse(InitialIndex:FinalIndex);
                
                hold(app.UIAxes, 'off');
                
                for i=1:app.GlobalNumberOfChannels %for every channel
                    color = 'red';
                    if i == app.GlobalChannelNumber
                        color = 'green';
                    end
                    for j=1:length(SpikeToUse) %for every spike
                        y = app.GlobalSpikeWaveforms(i, :, SpikeToUse(j)) + app.GlobalAmplitude*(app.GlobalChannelNumber-i);
                        plot(app.UIAxes,x,y,color);
                        hold(app.UIAxes, 'on');
                    end
                end
            end
            
            close(d);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Plot the cutting lines again
            TimepointEditFieldValueChanged(app, event);
            LowerthresholdEditFieldValueChanged(app, event);
            UpperthresholdEditFieldValueChanged(app, event);
        end

        % Value changed function: TimepointEditField
        function TimepointEditFieldValueChanged(app, event)
            %Obtain changed value
            app.GlobalTimepointNumber = app.TimepointEditField.Value;
            
            %Plot y line
            y = get(app.UIAxes,'YLim');
            x = ones(size(y)).*app.GlobalTimepointNumber;
            delete(app.GlobalYLine);
            hold(app.UIAxes, 'on');
            app.GlobalYLine = plot(app.UIAxes,x,y,'black');
            
            %This draws the vertical line of the selected bins waveform
            app.HistogramTimepointNumber = app.GlobalTimepointNumber;
            y = get(app.UIAxes_2,'YLim');
            x = ones(size(y)).*app.HistogramTimepointNumber;
            delete(app.HistogramYLine);
            hold(app.UIAxes_2, 'on');
            app.HistogramYLine = plot(app.UIAxes_2,x,y,'black');
        end

        % Value changed function: LowerthresholdEditField
        function LowerthresholdEditFieldValueChanged(app, event)
            %Obtain changed value
            app.GlobalLowerThreshold = app.LowerthresholdEditField.Value;
            
            %Plot x2 line
            x = 0:0.1:33;
            y = ones(size(x)).*app.GlobalLowerThreshold;
            delete(app.GlobalX2Line);
            hold(app.UIAxes, 'on');
            app.GlobalX2Line = plot(app.UIAxes,x,y,'black');
            
            app.HistogramLowerThreshold = app.GlobalLowerThreshold;
            x = 0:0.1:33;
            y = ones(size(x)).*app.HistogramLowerThreshold;
            delete(app.HistogramX2Line);
            hold(app.UIAxes_2, 'on');
            app.HistogramX2Line = plot(app.UIAxes_2,x,y,'black');
        end

        % Button pushed function: CutspikesfromwaveformButton
        function CutspikesfromwaveformButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Find the spikes that meet the condition
            e = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Splitting cluster.');
            
            SpikeToUse =[];
            j = 1;
            for i=1:length(app.GlobalCluArray)
                if app.GlobalCluArray(i) == app.GlobalClusterNumber
                    SpikeToUse(j) = i;
                    j = j +1;
                end
            end
            
            app.GlobalMaxBatchIndex = ceil(length(SpikeToUse)/app.GlobalBatchSize);
            if app.GlobalMaxBatchIndex < 1
                app.GlobalMaxBatchIndex = 1;
            end
            %shorten spikes to use
            NumberOfSpikes = length(SpikeToUse);
            InitialIndex = app.GlobalBatchSize*(app.GlobalBatchIndex-1)+1;
            FinalIndex = InitialIndex + app.GlobalBatchSize-1;
            if (FinalIndex > NumberOfSpikes)
                FinalIndex = NumberOfSpikes;
            end
            SpikeToUse = SpikeToUse(InitialIndex:FinalIndex);
            
            for i=1:length(SpikeToUse) %For every spike
                e.Value = i/length(SpikeToUse);
                if (app.GlobalSpikeWaveforms(app.GlobalChannelNumber, app.GlobalTimepointNumber,SpikeToUse(i)) > app.GlobalLowerThreshold) %If lower threshold condition is met
                    if (app.GlobalSpikeWaveforms(app.GlobalChannelNumber, app.GlobalTimepointNumber,SpikeToUse(i)) < app.GlobalUpperThreshold) %If upper threshold condition is met
                        app.GlobalCluArray(SpikeToUse(i)) = app.GlobalNewClusterNumber;
                    end
                end
            end
            close(e);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Plot the cluster again
            %first get an array with the spikes that belong to the cluster
            LoadwaveformsButtonPushed(app, event);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Plot the cutting lines again
            TimepointEditFieldValueChanged(app, event);
            LowerthresholdEditFieldValueChanged(app, event);
            UpperthresholdEditFieldValueChanged(app, event);
            
            temp = num2str(unique(app.GlobalCluArray),'%d');
            app.SelectaclusterDropDown.Items = cellstr(temp);
            
        end

        % Button pushed function: Selectclu1fileButton
        function Selectclu1fileButtonPushed(app, event)
            [app.GlobalBasename,app.GlobalPath] = uigetfile('*.clu.1');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            app.GlobalBasename = app.GlobalBasename(1:end-6);
            [app.GlobalSpikeWaveforms, app.GlobalTimepointsArray, app.GlobalCluArray] = load_klusters_output(app, app.GlobalPath, app.GlobalBasename);
            %Determine new cluster
            app.GlobalBatchIndex = 1;
            app.GlobalNewClusterNumber = max(app.GlobalCluArray)+1;
            app.DiscardedspikeswillbeplacedinclusternumberLabel.Text= ['Discarded spikes will be placed in cluster number: ' num2str(app.GlobalNewClusterNumber)];
            temp = num2str(unique(app.GlobalCluArray),'%d');
            app.SelectaclusterDropDown.Items = cellstr(temp);
            a = size(app.GlobalSpikeWaveforms);
            temp2 = num2str((1:a(1))');
            app.SelectachannelDropDown.Items = cellstr(temp2);
            
            plotHistogram(app);
            HistogramLoadWaveform(app, event);
            LoadwaveformsButtonPushed(app, event);
            
            
        end

        % Value changed function: UpperthresholdEditField
        function UpperthresholdEditFieldValueChanged(app, event)
            %Obtain changed value
            app.GlobalUpperThreshold = app.UpperthresholdEditField.Value;
            
            %Plot x1 line
            x = 0:0.1:33;
            y = ones(size(x)).*app.GlobalUpperThreshold;
            delete(app.GlobalX1Line);
            hold(app.UIAxes, 'on');
            app.GlobalX1Line = plot(app.UIAxes,x,y,'black');
            
            %Add in here
            app.HistogramUpperThreshold = app.GlobalUpperThreshold;
            x = 0:0.1:33;
            y = ones(size(x)).*app.HistogramUpperThreshold;
            delete(app.HistogramX1Line);
            hold(app.UIAxes_2, 'on');
            app.HistogramX1Line = plot(app.UIAxes_2,x,y,'black');
        end

        % Value changed function: BatchsizeEditField
        function BatchsizeEditFieldValueChanged(app, event)
            app.GlobalBatchIndex = 1;
            app.GlobalBatchSize = app.BatchsizeEditField.Value;
            LoadwaveformsButtonPushed(app, event);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.GlobalBatchIndex < app.GlobalMaxBatchIndex
                app.GlobalBatchIndex = app.GlobalBatchIndex +1;
                LoadwaveformsButtonPushed(app, event);
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.GlobalBatchIndex > 1
                app.GlobalBatchIndex = app.GlobalBatchIndex - 1;
                LoadwaveformsButtonPushed(app, event);
            end
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Saving changes, please wait.',...
                'Indeterminate','on');
            app.GlobalCluArray = [max(app.GlobalCluArray); app.GlobalCluArray];
            fid = fopen(char(strcat(app.GlobalPath, app.GlobalBasename, '.clu.1')), 'w');
            fprintf(fid,'%d\n', app.GlobalCluArray);
            fclose(fid);
            app.GlobalCluArray = app.GlobalCluArray(2:end);
            close(d);
        end

        % Value changed function: SelectaclusterDropDown
        function SelectaclusterDropDownValueChanged(app, event)
            app.GlobalClusterNumber = str2double(app.SelectaclusterDropDown.Value);
            plotHistogram(app);
            HistogramLoadWaveform(app, event);
            LoadwaveformsButtonPushed(app, event);
        end

        % Value changed function: SelectachannelDropDown
        function SelectachannelDropDownValueChanged(app, event)
            app.GlobalChannelNumber = str2double(app.SelectachannelDropDown.Value);
            app.HistogramChannelNumber = app.GlobalChannelNumber;
            HistogramLoadWaveform(app, event);
            LoadwaveformsButtonPushed(app, event);
        end

        % Value changed function: HistogramdurationmsEditField
        function HistogramdurationmsEditFieldValueChanged(app, event)
            app.GlobalHistogramDuration = app.HistogramdurationmsEditField.Value;
            plotHistogram(app);
        end

        % Value changed function: PlotbinsinradiusEditField
        function PlotbinsinradiusEditFieldValueChanged(app, event)
            app.HistogramPlotRadius = app.PlotbinsinradiusEditField.Value;
            HistogramLoadWaveform(app, event);
        end

        % Callback function
        function SelectachannelDropDown_2ValueChanged(app, event)
            
        end

        % Callback function
        function TimepointEditField_2ValueChanged(app, event)
            
        end

        % Callback function
        function LowerthresholdEditField_2ValueChanged(app, event)
            app.HistogramLowerThreshold = app.LowerthresholdEditField_2.Value;
            x = 0:0.1:33;
            y = ones(size(x)).*app.HistogramLowerThreshold;
            delete(app.HistogramX2Line);
            hold(app.UIAxes_2, 'on');
            app.HistogramX2Line = plot(app.UIAxes_2,x,y,'red');
        end

        % Button pushed function: CutspikesfrombinButton
        function CutspikesfrombinButtonPushed(app, event)
            %first get an array with the spikes that belong to the cluster
            time_length_in_timepoints = app.GlobalSamplingFrequency * app.HistogramPlotRadius * 10^-3;
            
            d = uiprogressdlg(app.UIFigure,'Title','Plotting the data, please wait.',...
                'Indeterminate','on');
            
            %Figure out what spikes belong to our cluster
            SpikeToUse =[];
            TimesToUse =[];
            j = 1;
            for i=1:length(app.GlobalCluArray)
                if app.GlobalCluArray(i) == app.GlobalClusterNumber
                    SpikeToUse(j) = i; %This is storing the index of the spike that matches the cluster number
                    TimesToUse(j) = app.GlobalTimepointsArray(i); %This is storing the timepoint of the spike we just stored
                    j = j +1;
                end
            end
            
            %Figure out if the spikes that fired within the determined bin
            data = [];
            data_index = 1;
            
            for i=1:length(SpikeToUse)%For every spike in the cluster
                
                before = 1;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Check spikes before this one
                while true
                    %If we run out of spikes, end the loop
                    if i-before < 1
                        break
                    end
                    %If spike is outside our time length, end the loop
                    if (TimesToUse(i) - TimesToUse(i-before) > time_length_in_timepoints)
                        break
                    end
                    data(data_index) = SpikeToUse(i-before); %This spike meets the criteria
                    data_index = data_index + 1;
                    before = before + 1;
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Check spikes after this one
                after = 1;
                while true
                    if i+after > length(SpikeToUse)
                        break
                    end
                    if (TimesToUse(i+after) - TimesToUse(i) > time_length_in_timepoints)
                        break
                    end
                    data(data_index) = SpikeToUse(i+after);
                    data_index = data_index + 1;
                    after = after + 1;
                end
            end
            
            data = unique(data);
            
            app.GlobalMaxBatchIndex_2 = ceil(length(data)/app.GlobalBatchSize_2);
            if app.GlobalMaxBatchIndex_2 < 1
                app.GlobalMaxBatchIndex_2 = 1;
            end
            %shorten spikes to use
            NumberOfSpikes_2 = length(data);
            InitialIndex_2 = app.GlobalBatchSize_2*(app.GlobalBatchIndex_2-1)+1;
            FinalIndex_2 = InitialIndex_2 + app.GlobalBatchSize_2-1;
            if (FinalIndex_2 > NumberOfSpikes_2)
                FinalIndex_2 = NumberOfSpikes_2;
            end
            data = data(InitialIndex_2:FinalIndex_2);
            
            for i=1:length(data) %For every spike
                if (app.GlobalSpikeWaveforms(app.HistogramChannelNumber, app.HistogramTimepointNumber,data(i)) > app.HistogramLowerThreshold) %If lower threshold condition is met
                    if (app.GlobalSpikeWaveforms(app.HistogramChannelNumber, app.HistogramTimepointNumber,data(i)) < app.HistogramUpperThreshold) %If upper threshold condition is met
                        app.GlobalCluArray(data(i)) = app.GlobalNewClusterNumber;
                    end
                end
            end
            
            %Ok now we need ro replot the histogram, the histogram waveforms and also the general waveforms
            plotHistogram(app);
            HistogramLoadWaveform(app, event);
            LoadwaveformsButtonPushed(app, event);
            
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            if app.GlobalBatchIndex_2 < app.GlobalMaxBatchIndex_2
                app.GlobalBatchIndex_2 = app.GlobalBatchIndex_2 +1;
                HistogramLoadWaveform(app, event);
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            if app.GlobalBatchIndex_2 < app.GlobalMaxBatchIndex_2
                app.GlobalBatchIndex_2 = app.GlobalBatchIndex_2 +1;
                HistogramLoadWaveform(app, event);
            end
        end

        % Value changed function: BatchsizeEditField_2
        function BatchsizeEditField_2ValueChanged(app, event)
            app.GlobalBatchIndex_2 = 1;
            app.GlobalBatchSize_2 = app.BatchsizeEditField_2.Value;
            HistogramLoadWaveform(app, event);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Increase amplitude
            if app.GlobalAmplitude > 199
                app.GlobalAmplitude = app.GlobalAmplitude - 100;
            elseif app.GlobalAmplitude > 10
                app.GlobalAmplitude = app.GlobalAmplitude - 10;
            else
                app.GlobalAmplitude = 0;
            end
            HistogramLoadWaveform(app, event);
            LoadwaveformsButtonPushed(app, event);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %Decrease amplitude
            if app.GlobalAmplitude > 99
                app.GlobalAmplitude = app.GlobalAmplitude + 100;
            else
                app.GlobalAmplitude = app.GlobalAmplitude + 10;
            end
            HistogramLoadWaveform(app, event);
            LoadwaveformsButtonPushed(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cluster Waveforms')
            xlabel(app.UIAxes, 'Timepoints')
            ylabel(app.UIAxes, 'Voltage (uV)')
            app.UIAxes.XLim = [0 33];
            app.UIAxes.XTick = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.WaveformCutterAppLabel = uilabel(app.UIFigure);
            app.WaveformCutterAppLabel.Text = 'Waveform Cutter App';
            app.CutspikesfromwaveformButton = uibutton(app.UIFigure, 'push');
            app.CutspikesfromwaveformButton.ButtonPushedFcn = createCallbackFcn(app, @CutspikesfromwaveformButtonPushed, true);
            app.CutspikesfromwaveformButton.Text = 'Cut spikes from waveform';
            app.TimepointEditFieldLabel = uilabel(app.UIFigure);
            app.TimepointEditFieldLabel.Text = 'Timepoint #';
            app.TimepointEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimepointEditField.Limits = [0 32];
            app.TimepointEditField.RoundFractionalValues = 'on';
            app.TimepointEditField.ValueChangedFcn = createCallbackFcn(app, @TimepointEditFieldValueChanged, true);
            app.LowerthresholdEditFieldLabel = uilabel(app.UIFigure);
            app.LowerthresholdEditFieldLabel.Text = 'Lower threshold';
            app.LowerthresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @LowerthresholdEditFieldValueChanged, true);
            app.Selectclu1fileButton = uibutton(app.UIFigure, 'push');
            app.Selectclu1fileButton.ButtonPushedFcn = createCallbackFcn(app, @Selectclu1fileButtonPushed, true);
            app.Selectclu1fileButton.Text = 'Select .clu.1 file';
            app.UpperthresholdEditFieldLabel = uilabel(app.UIFigure);
            app.UpperthresholdEditFieldLabel.Text = 'Upper threshold';
            app.UpperthresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @UpperthresholdEditFieldValueChanged, true);
            app.DiscardedspikeswillbeplacedinclusternumberLabel = uilabel(app.UIFigure);
            app.DiscardedspikeswillbeplacedinclusternumberLabel.Text = 'Discarded spikes will be placed in cluster number:';
            app.BatchsizeEditFieldLabel = uilabel(app.UIFigure);
            app.BatchsizeEditFieldLabel.Text = 'Batch size';
            app.BatchsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BatchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @BatchsizeEditFieldValueChanged, true);
            app.BatchsizeEditField.Value = 100;
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SavechangesButton = uibutton(app.UIFigure, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.SelectaclusterLabel = uilabel(app.UIFigure);
            app.SelectaclusterLabel.Text = 'Select a cluster #';
            app.SelectaclusterDropDown = uidropdown(app.UIFigure);
            app.SelectaclusterDropDown.Items = {'None'};
            app.SelectaclusterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectaclusterDropDownValueChanged, true);
            app.SelectaclusterDropDown.Value = 'None';
            app.SelectachannelDropDownLabel = uilabel(app.UIFigure);
            app.SelectachannelDropDownLabel.Text = 'Select a channel #';
            app.SelectachannelDropDown = uidropdown(app.UIFigure);
            app.SelectachannelDropDown.Items = {'None'};
            app.SelectachannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectachannelDropDownValueChanged, true);
            app.SelectachannelDropDown.Value = 'None';
            app.GlobalHistogram = uiaxes(app.UIFigure);
            title(app.GlobalHistogram, 'Cluster Autocorrelogram')
            app.HistogramdurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.HistogramdurationmsEditFieldLabel.Text = 'Histogram duration (ms)';
            app.HistogramdurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.HistogramdurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @HistogramdurationmsEditFieldValueChanged, true);
            app.HistogramdurationmsEditField.Value = 20;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Spikes from selected bins')
            xlabel(app.UIAxes_2, 'Timepoints')
            ylabel(app.UIAxes_2, 'Voltage (uV)')
            app.UIAxes_2.XLim = [0 33];
            app.UIAxes_2.XTick = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.UIAxes_2.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'; '22'; '24'; '26'; '28'; '30'; '32'};
            app.PlotbinsinradiusEditFieldLabel = uilabel(app.UIFigure);
            app.PlotbinsinradiusEditFieldLabel.Text = 'Plot bins in radius';
            app.PlotbinsinradiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotbinsinradiusEditField.ValueChangedFcn = createCallbackFcn(app, @PlotbinsinradiusEditFieldValueChanged, true);
            app.PlotbinsinradiusEditField.Value = 1;
            app.CutspikesfrombinButton = uibutton(app.UIFigure, 'push');
            app.CutspikesfrombinButton.ButtonPushedFcn = createCallbackFcn(app, @CutspikesfrombinButtonPushed, true);
            app.CutspikesfrombinButton.Text = 'Cut spikes from bin';
            app.NextButton_2 = uibutton(app.UIFigure, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.PreviousButton_2 = uibutton(app.UIFigure, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = 'Previous';
            app.BatchsizeEditField_2Label = uilabel(app.UIFigure);
            app.BatchsizeEditField_2Label.Text = 'Batch size';
            app.BatchsizeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BatchsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @BatchsizeEditField_2ValueChanged, true);
            app.BatchsizeEditField_2.Value = 100;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
        end
    end

    methods (Access = public)

        % Construct app
        function app = WaveformCutter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end